classdef demoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OdomLabelPos               matlab.ui.control.Label
        Titel                      matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        JSONdata                   matlab.ui.control.Label
        LatestreceivedodomL2Label  matlab.ui.control.Label
        OdomLabelOrientation       matlab.ui.control.Label
        SwitchLabel                matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        CameraAxes                 matlab.ui.control.UIAxes
        LidarAxes                  matlab.ui.control.UIAxes
        L2Axes                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        TimerSubOdom
        TimerL2Sub
        TimerOdom
        odomSub
        scanSub
        camSub
        plotScan
    end
    
    methods (Access = private)
        function TimerSubOdomFcn(app,~,~)
            app.JSONdata.Text=fileread('/home/cedricplouvier/Documents/PycharmProjects/distributed-worldmap-PubSubOdometryData/data.json');
        end        
        
        function TimerL2SubFcn(app,~,~)
            img=imread('/home/cedricplouvier/Documents/PycharmProjects/distributed-worldmap-L2PubSub/resources/ReceivedL2.png');
            imshow(img, 'Parent', app.L2Axes);
        end
        
        function TimerOdomFcn(app,~,~)
            odomMsg = receive(app.odomSub);
            camMsg = receive(app.camSub);
            scanMsg = receive(app.scanSub);
            
            pos = odomMsg.Pose.Pose;
            app.OdomLabelPos.Text = sprintf([' position x: %s\n' ...
                ' position y: %s\n' ...
                ' position z: %s\n'], string(pos.Position.X), string(pos.Position.Y), string(pos.Position.Z));
            app.OdomLabelOrientation.Text = sprintf([' orientation x: %s\n' ...
                ' orientation y: %s\n' ...
                ' orientation z: %s\n' ...
                ' orientation w: %s\n'], string(pos.Orientation.X), string(pos.Orientation.Y), string(pos.Orientation.Z), string(pos.Orientation.W));
            %map=readBinaryOccupancyGrid(scanMsg);

            plot(scanMsg, 'Parent', app.LidarAxes);
            img = readImage(camMsg);
            imshow(img, 'Parent', app.CameraAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TimerSubOdom = timer('ExecutionMode', 'fixedRate', 'Period', 0.2,'Busymode', 'queue', 'TimerFcn', @app.TimerSubOdomFcn);
            app.TimerL2Sub = timer('ExecutionMode', 'fixedRate', 'Period', 1,'Busymode', 'queue', 'TimerFcn', @app.TimerL2SubFcn);
            app.TimerOdom = timer('ExecutionMode', 'fixedRate', 'Period', 0.1,'Busymode', 'queue', 'TimerFcn', @app.TimerOdomFcn);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            rosshutdown
            stop(app.TimerSubOdom)
            stop(app.TimerL2Sub)
            stop(app.TimerOdom)
            delete(app.TimerSubOdom)
            delete(app.TimerL2Sub)
            delete(app.TimerOdom)
            delete(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %rosinit
            %app.odomSub = rossubscriber('/odom');
            %app.scanSub = rossubscriber('/scan');
            %app.camSub = rossubscriber('/camera/rgb/image_raw');
            
            if strcmp(app.TimerSubOdom.Running, 'off')
               start(app.TimerSubOdom);
            end
            if strcmp(app.TimerL2Sub.Running, 'off')
               start(app.TimerL2Sub);
            end
            %if strcmp(app.TimerOdom.Running, 'off')
            %   start(app.TimerOdom);
            %end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %rosshutdown
            stop(app.TimerSubOdom)
            stop(app.TimerL2Sub)
            stop(app.TimerOdom)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                    rosinit
                    app.odomSub = rossubscriber('/odom');
                    app.scanSub = rossubscriber('/scan');
                    app.camSub = rossubscriber('/camera/rgb/image_raw');
                    
                    if strcmp(app.TimerOdom.Running, 'off')
                       start(app.TimerOdom);
                    end
                case 'Off'
                    rosshutdown
                    stop(app.TimerOdom)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OdomLabelPos = uilabel(app.UIFigure);
            app.OdomLabelPos.Text = 'Not connected to ros master URI';
            app.Titel = uilabel(app.UIFigure);
            app.Titel.Text = 'SmartWareHouse';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.JSONdata = uilabel(app.UIFigure);
            app.JSONdata.Text = {'No Data Yet'; ''};
            app.LatestreceivedodomL2Label = uilabel(app.UIFigure);
            app.LatestreceivedodomL2Label.Text = 'Latest received odom L2';
            app.OdomLabelOrientation = uilabel(app.UIFigure);
            app.OdomLabelOrientation.Text = 'Not connectedto ros master URI';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.CameraAxes = uiaxes(app.UIFigure);
            title(app.CameraAxes, 'Turtlebot camera')
            app.CameraAxes.XTick = [];
            app.CameraAxes.YTick = [];
            app.LidarAxes = uiaxes(app.UIFigure);
            title(app.LidarAxes, 'Lidar Scan')
            app.LidarAxes.XTick = [];
            app.LidarAxes.YTick = [];
            app.L2Axes = uiaxes(app.UIFigure);
            title(app.L2Axes, 'L2')
            app.L2Axes.XTick = [];
            app.L2Axes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end